classdef interfaz_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        DesarrolladoporLorenaPinaPellnLabel  matlab.ui.control.Label
        IncluirArmonicosCheckBox        matlab.ui.control.CheckBox
        FrecMuestreoEditField           matlab.ui.control.NumericEditField
        FrecMuestreoHzEditFieldLabel    matlab.ui.control.Label
        DuracionEditField               matlab.ui.control.NumericEditField
        DuracinsEditFieldLabel          matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ANALIZARTEMBLORButton           matlab.ui.control.Button
        LongitudmEditField              matlab.ui.control.NumericEditField
        LongitudmEditFieldLabel         matlab.ui.control.Label
        AmortiguamientoNmsradEditField  matlab.ui.control.NumericEditField
        AmortiguamientoNmsradEditFieldLabel  matlab.ui.control.Label
        RigidezNmradEditField           matlab.ui.control.NumericEditField
        Rigidezk0NmradLabel             matlab.ui.control.Label
        GradoClinicoDropDown            matlab.ui.control.DropDown
        GradoClnicoDropDownLabel        matlab.ui.control.Label
        InerciakgmEditField             matlab.ui.control.NumericEditField
        InerciaIkgmLabel                matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ANALIZARTEMBLORButton
        function ANALIZARTEMBLORButtonPushed(app, event)
             % 1. LEER LOS PARÁMETROS DEL PACIENTE Y DE LA SEÑAL
            grado = str2double(app.GradoClinicoDropDown.Value); 
            
            I  = app.InerciakgmEditField.Value;
            k0 = app.RigidezNmradEditField.Value;
            c0 = app.AmortiguamientoNmsradEditField.Value;
            L  = app.LongitudmEditField.Value;
            
            T_dur = app.DuracionEditField.Value;
            Fs = app.FrecMuestreoEditField.Value;
            armonicos = app.IncluirArmonicosCheckBox.Value;
            
           % 2. ASIGNAR AMPLITUD Y FRECUENCIA BASE SEGÚN EL GRADO
            switch grado 
                case 0
                    f0 = 4.1016; 
                    A1 = 0.0075871; 
                    A2 = 0.0073253; 
                    ruido_rms = 0.03275;
                case 1
                    f0 = 5.0781; 
                    A1 = 0.0063163; 
                    A2 = 0.001537; 
                    ruido_rms = 0.023708;
                case 2
                    f0 = 4.1992; 
                    A1 = 0.015103; 
                    A2 = 0.0011089; 
                    ruido_rms = 0.040044;
                case 3
                    f0 = 4.3945; 
                    A1 = 0.042728; 
                    A2 = 0.0059399; 
                    ruido_rms = 0.17395;
                case 4
                    f0 = 4.5898; 
                    A1 = 0.25196; 
                    A2 = 0.064805; 
                    ruido_rms = 0.39448;
                otherwise
                    f0 = 4.5; A1 = 0.01; A2 = 0.01; ruido_rms = 0.01;
            end
                        
            % 3. GENERAR LA SEÑAL SINTÉTICA VALIDADA (en g)
            t = (0 : 1/Fs : T_dur)';
            
            % Añadimos desfases aleatorios
            phi1 = 2 * pi * rand();
            a_rel_sint_g = A1 * sin(2*pi*f0*t + phi1);
            
            if armonicos == 1
                phi2 = 2 * pi * rand();
                a_rel_sint_g = a_rel_sint_g + A2 * sin(2*pi*(2*f0)*t + phi2);
            end
            
            [b, a] = butter(2, [3, 15] / (Fs / 2), 'bandpass');
            
            if ruido_rms > 0
                ruido_blanco = randn(size(t));
                ruido_filt = filtfilt(b, a, ruido_blanco);
                ruido_esc = ruido_filt * (ruido_rms / rms(ruido_filt)); 
                a_rel_sint_g = a_rel_sint_g + ruido_esc;
            end
            
            % CONVERSIÓN (m/s^2)
            a_rel_sint = a_rel_sint_g * 9.81;
            
            % 4. CÁLCULO CINEMÁTICO 
            theta_ddot = a_rel_sint / L;
            
            theta_dot_raw = cumtrapz(t, theta_ddot);
            theta_dot = filtfilt(b, a, theta_dot_raw);
            
            theta_raw = cumtrapz(t, theta_dot);
            theta = filtfilt(b, a, theta_raw);
            
            % 5. MODELO DINÁMICO
            M_tremor = I * theta_ddot + c0 * theta_dot + k0 * theta;
            
            % 6. DIBUJAR EN LA INTERFAZ
            % Cinemática Angular
            plot(app.UIAxes4, t, theta_ddot, 'Color', [0 0.4470 0.7410], 'LineWidth', 1.2);
            app.UIAxes4.Title.String = 'Acel. Angular \theta_{ddot}';
            app.UIAxes4.XLabel.String = 'Tiempo (s)';
            app.UIAxes4.YLabel.String = 'rad/s^2';
            
            plot(app.UIAxes5, t, theta_dot, 'Color', [0.3010 0.7450 0.9330], 'LineWidth', 1.2);
            app.UIAxes5.Title.String = 'Vel. Angular \theta_{dot}';
            app.UIAxes5.XLabel.String = 'Tiempo (s)';
            app.UIAxes5.YLabel.String = 'rad/s';
            
            plot(app.UIAxes6, t, theta, 'Color', [0 0.5 0.5], 'LineWidth', 1.2);
            app.UIAxes6.Title.String = 'Desplazamiento \theta';
            app.UIAxes6.XLabel.String = 'Tiempo (s)';
            app.UIAxes6.YLabel.String = 'rad';
            
            plot(app.UIAxes, t, M_tremor, 'Color', [0.4660 0.6740 0.1880], 'LineWidth', 1.5);
            app.UIAxes.Title.String = 'M_{tremor} Equivalente';
            app.UIAxes.XLabel.String = 'Tiempo (s)';
            app.UIAxes.YLabel.String = 'N·m';

            % CÁLCULOS DE PSD (Para ambos: Aceleración Relativa y Momento)
            Tw = min(2.56, T_dur/2); Nw = round(Tw * Fs); win = hamming(Nw); 
            noverlap = round(0.5 * Nw); nfft = 2^(nextpow2(Nw) + 1);
            
            [P_a, f_a] = pwelch(a_rel_sint_g, win, noverlap, nfft, Fs);
            [P_M, f_M] = pwelch(M_tremor, win, noverlap, nfft, Fs);
            
            % Representación PSDs
            plot(app.UIAxes3, f_a(f_a<=15), P_a(f_a<=15), 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.5);
            app.UIAxes3.Title.String = 'PSD de Aceleración Relativa';
            app.UIAxes3.XLabel.String = 'Frecuencia (Hz)';
            app.UIAxes3.YLabel.String = 'Potencia (g^2/Hz)';
            
            plot(app.UIAxes2, f_M(f_M<=15), P_M(f_M<=15), 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 1.5);
            app.UIAxes2.Title.String = 'PSD de M_{tremor} Sintético';
            app.UIAxes2.XLabel.String = 'Frecuencia (Hz)';
            app.UIAxes2.YLabel.String = 'Potencia ((N·m)^2/Hz)';
            
            % 7. ACTUALIZAR TABLA CON RESULTADOS MULTIPLES
            % Búsqueda de picos en el entorno de la frecuencia teórica
            margen = 1.5; 
            idx_busqueda = (f_M >= f0 - margen) & (f_M <= f0 + margen);
            
            % Extracción para momento
            f_busqueda_M = f_M(idx_busqueda);
            P_busqueda_M = P_M(idx_busqueda);
            if isempty(P_busqueda_M) % Seguridad
                f_busqueda_M = f_M(f_M <= 15);
                P_busqueda_M = P_M(f_M <= 15);
            end
            pot_max_M = max(P_busqueda_M);
            frec_dom_M = f_busqueda_M(P_busqueda_M == pot_max_M); 
            frec_dom_M = frec_dom_M(1);
            rms_M = rms(M_tremor);
            
            % Extracción para aceleración relativa
            f_busqueda_a = f_a(idx_busqueda);
            P_busqueda_a = P_a(idx_busqueda);
            if isempty(P_busqueda_a) % Seguridad
                f_busqueda_a = f_a(f_a <= 15);
                P_busqueda_a = P_a(f_a <= 15);
            end
            pot_max_a = max(P_busqueda_a);
            frec_dom_a = f_busqueda_a(P_busqueda_a == pot_max_a); 
            frec_dom_a = frec_dom_a(1);
            rms_a = rms(a_rel_sint_g);
            
            % Construcción de la Tabla
            % Fila 1: Aceleración | Fila 2: Momento
            app.UITable.Data = {
                rms_a, frec_dom_a, pot_max_a;
                rms_M, frec_dom_M, pot_max_M
            };
            
            app.UITable.ColumnName = {'RMS', 'Frec. Dom. (Hz)', 'Pot. Máx'};
            
            % Añadimos los nombres de las filas para indicar las unidades 
            app.UITable.RowName = {'Acel. Relativa (g)', 'Momento Sintético (N·m)'};
   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.InerciaIkgmLabel = uilabel(app.UIFigure);
            app.InerciaIkgmLabel.Text = 'Inercia (kg·m²)';
            app.InerciakgmEditField = uieditfield(app.UIFigure, 'numeric');
            app.InerciakgmEditField.Value = 0.0025;
            app.GradoClnicoDropDownLabel = uilabel(app.UIFigure);
            app.GradoClnicoDropDownLabel.Text = 'Grado Clínico';
            app.GradoClinicoDropDown = uidropdown(app.UIFigure);
            app.GradoClinicoDropDown.Items = {'0', '1', '2', '3', '4'};
            app.GradoClinicoDropDown.Value = '0';
            app.Rigidezk0NmradLabel = uilabel(app.UIFigure);
            app.Rigidezk0NmradLabel.Text = 'Rigidez (N·m/rad)';
            app.RigidezNmradEditField = uieditfield(app.UIFigure, 'numeric');
            app.RigidezNmradEditField.Value = 0.65;
            app.AmortiguamientoNmsradEditFieldLabel = uilabel(app.UIFigure);
            app.AmortiguamientoNmsradEditFieldLabel.Text = 'Amortiguamiento (N·m·s/rad)';
            app.AmortiguamientoNmsradEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmortiguamientoNmsradEditField.Value = 0.03;
            app.LongitudmEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudmEditFieldLabel.Text = 'Longitud (m)';
            app.LongitudmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudmEditField.Value = 0.15;
            app.ANALIZARTEMBLORButton = uibutton(app.UIFigure, 'push');
            app.ANALIZARTEMBLORButton.ButtonPushedFcn = createCallbackFcn(app, @ANALIZARTEMBLORButtonPushed, true);
            app.ANALIZARTEMBLORButton.Text = 'ANALIZAR TEMBLOR';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.DuracinsEditFieldLabel = uilabel(app.UIFigure);
            app.DuracinsEditFieldLabel.Text = 'Duración (s)';
            app.DuracionEditField = uieditfield(app.UIFigure, 'numeric');
            app.DuracionEditField.Value = 17;
            app.FrecMuestreoHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrecMuestreoHzEditFieldLabel.Text = 'Frec. Muestreo (Hz)';
            app.FrecMuestreoEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecMuestreoEditField.Value = 100;
            app.IncluirArmonicosCheckBox = uicheckbox(app.UIFigure);
            app.IncluirArmonicosCheckBox.Text = 'Incluir Armónicos';
            app.DesarrolladoporLorenaPinaPellnLabel = uilabel(app.UIFigure);
            app.DesarrolladoporLorenaPinaPellnLabel.Text = 'Desarrollado por: Lorena Pina Pellín';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'"Análisis y parametrización de señales de temblor esencial'; 'para generación de excitaciones sintéticas en el miembro superior"'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logoUA.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end