classdef interfaz_tfg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IncluirArmonicosCheckBox        matlab.ui.control.CheckBox
        FrecMuestreoEditField           matlab.ui.control.NumericEditField
        FrecMuestreoHzEditFieldLabel    matlab.ui.control.Label
        DuracionEditField               matlab.ui.control.NumericEditField
        DuracinsEditFieldLabel          matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ANALIZARTEMBLORButton           matlab.ui.control.Button
        LongitudmEditField              matlab.ui.control.NumericEditField
        LongitudmEditFieldLabel         matlab.ui.control.Label
        AmortiguamientoNmsradEditField  matlab.ui.control.NumericEditField
        AmortiguamientoNmsradEditFieldLabel  matlab.ui.control.Label
        RigidezNmradEditField           matlab.ui.control.NumericEditField
        Rigidezk0NmradLabel             matlab.ui.control.Label
        GradoClinicoDropDown            matlab.ui.control.DropDown
        GradoClnicoDropDownLabel        matlab.ui.control.Label
        InerciakgmEditField             matlab.ui.control.NumericEditField
        InerciaIkgmLabel                matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ANALIZARTEMBLORButton
        function ANALIZARTEMBLORButtonPushed(app, event)
            % 1. LEER LOS PARÁMETROS DEL PACIENTE Y DE LA SEÑAL
            grado = str2double(app.GradoClinicoDropDown.Value); 
            
            I  = app.InerciakgmEditField.Value;
            k0 = app.RigidezNmradEditField.Value;
            c0 = app.AmortiguamientoNmsradEditField.Value;
            L  = app.LongitudmEditField.Value;
            
            T_dur = app.DuracionEditField.Value;
            Fs = app.FrecMuestreoEditField.Value;
            armonicos = app.IncluirArmonicosCheckBox.Value;
            
           % 2. ASIGNAR AMPLITUD Y FRECUENCIA BASE SEGÚN EL GRADO
            switch grado 
                case 0
                    f0 = 4.1016; 
                    A1 = 0.0075871; 
                    A2 = 0.0073253; 
                    ruido_rms = 0.03275;
                case 1
                    f0 = 5.0781; 
                    A1 = 0.0063163; 
                    A2 = 0.001537; 
                    ruido_rms = 0.023708;
                case 2
                    f0 = 4.1992; 
                    A1 = 0.015103; 
                    A2 = 0.0011089; 
                    ruido_rms = 0.040044;
                case 3
                    f0 = 4.3945; 
                    A1 = 0.042728; 
                    A2 = 0.0059399; 
                    ruido_rms = 0.17395;
                case 4
                    f0 = 4.5898; 
                    A1 = 0.25196; 
                    A2 = 0.064805; 
                    ruido_rms = 0.39448;
                otherwise
                    f0 = 4.5; A1 = 0.01; A2 = 0.01; ruido_rms = 0.01;
            end
                        
            % 3. GENERAR LA SEÑAL SINTÉTICA VALIDADA (en g)
            t = (0 : 1/Fs : T_dur)';
            
            % Añadimos desfases aleatorios
            phi1 = 2 * pi * rand();
            a_rel_sint_g = A1 * sin(2*pi*f0*t + phi1);
            
            if armonicos == 1
                phi2 = 2 * pi * rand();
                a_rel_sint_g = a_rel_sint_g + A2 * sin(2*pi*(2*f0)*t + phi2);
            end
            
            [b, a] = butter(2, [3, 15] / (Fs / 2), 'bandpass');
            
            if ruido_rms > 0
                ruido_blanco = randn(size(t));
                ruido_filt = filtfilt(b, a, ruido_blanco);
                ruido_esc = ruido_filt * (ruido_rms / rms(ruido_filt)); 
                a_rel_sint_g = a_rel_sint_g + ruido_esc;
            end
            
            % CONVERSIÓN (m/s^2)
            a_rel_sint = a_rel_sint_g * 9.81;
            
            % 4. CÁLCULO CINEMÁTICO 
            theta_ddot = a_rel_sint / L;
            
            theta_dot_raw = cumtrapz(t, theta_ddot);
            theta_dot = filtfilt(b, a, theta_dot_raw);
            
            theta_raw = cumtrapz(t, theta_dot);
            theta = filtfilt(b, a, theta_raw);
            
            % 5. MODELO DINÁMICO
            M_tremor = I * theta_ddot + c0 * theta_dot + k0 * theta;
            
            % 6. DIBUJAR EN LA INTERFAZ
            plot(app.UIAxes3, t, a_rel_sint_g, 'Color', [0 0.4470 0.7410]);
            app.UIAxes3.Title.String = 'a_{rel,sint} (g)';
            app.UIAxes3.XLabel.String = 'Tiempo (s)';
            app.UIAxes3.YLabel.String = 'Acel. (g)';
            
            plot(app.UIAxes4, t, theta_ddot, 'Color', [0.8500 0.3250 0.0980]);
            app.UIAxes4.Title.String = 'Acel. Angular \theta_{ddot} (rad/s^2)';
            app.UIAxes4.XLabel.String = 'Tiempo (s)';
            app.UIAxes4.YLabel.String = 'Acel. (rad/s^2)';
            
            plot(app.UIAxes5, t, theta_dot, 'Color', [0.9290 0.6940 0.1250]);
            app.UIAxes5.Title.String = 'Vel. Angular \theta_{dot} (rad/s)';
            app.UIAxes5.XLabel.String = 'Tiempo (s)';
            app.UIAxes5.YLabel.String = 'Vel. (rad/s)';
            
            plot(app.UIAxes6, t, theta, 'Color', [0.4940 0.1840 0.5560]);
            app.UIAxes6.Title.String = 'Desplazamiento \theta (rad)';
            app.UIAxes6.XLabel.String = 'Tiempo (s)';
            app.UIAxes6.YLabel.String = 'Ángulo (rad)';
            
            plot(app.UIAxes, t, M_tremor, 'Color', [0.4660 0.6740 0.1880], 'LineWidth', 1.5);
            app.UIAxes.Title.String = 'M_{tremor} Equivalente (N·m)';
            app.UIAxes.XLabel.String = 'Tiempo (s)';
            app.UIAxes.YLabel.String = 'Momento (N·m)';
            
            Tw = min(2.56, T_dur/2); Nw = round(Tw * Fs); win = hamming(Nw); 
            noverlap = round(0.5 * Nw); nfft = 2^(nextpow2(Nw) + 1);
            [P_M, f_M] = pwelch(M_tremor, win, noverlap, nfft, Fs);
        
            plot(app.UIAxes2, f_M(f_M<=15), P_M(f_M<=15), 'Color', [0.4660 0.6740 0.1880], 'LineWidth', 2);
            app.UIAxes2.Title.String = 'PSD de M_{tremor}';
            app.UIAxes2.XLabel.String = 'Frecuencia (Hz)';
            app.UIAxes2.YLabel.String = 'Potencia ((N·m)^2/Hz)';
            
            % 7. ACTUALIZAR TABLA CON RESULTADOS
            rms_M = rms(M_tremor);
            
            % Buscamos el pico SOLO en el entorno de la frecuencia teórica conocida
            % para evitar que el ruido extremo enmascare la lectura real
            margen = 1.5; % Buscamos en f0 +/- 1.5 Hz
            idx_busqueda = (f_M >= f0 - margen) & (f_M <= f0 + margen);
            
            f_busqueda = f_M(idx_busqueda);
            P_busqueda = P_M(idx_busqueda);
            
            % Si por algún motivo el array está vacío, usamos todo el rango por seguridad
            if isempty(P_busqueda)
                f_busqueda = f_M(f_M <= 15);
                P_busqueda = P_M(f_M <= 15);
            end
            
            potencia_max = max(P_busqueda);
            frec_dom_grafica = f_busqueda(P_busqueda == potencia_max); 
            frec_dom_grafica = frec_dom_grafica(1); 
            
            % Actualizamos los datos y forzamos de nuevo los nombres de las columnas
            app.UITable.Data = {rms_M, frec_dom_grafica, potencia_max};
            app.UITable.ColumnName = {'RMS (N·m)', 'Frec. Dom. (Hz)', 'Pot. Máx ((N·m)²/Hz)'};
   

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.InerciaIkgmLabel = uilabel(app.UIFigure);
            app.InerciaIkgmLabel.Text = 'Inercia (kg·m²)';
            app.InerciakgmEditField = uieditfield(app.UIFigure, 'numeric');
            app.InerciakgmEditField.Value = 0.0025;
            app.GradoClnicoDropDownLabel = uilabel(app.UIFigure);
            app.GradoClnicoDropDownLabel.Text = 'Grado Clínico';
            app.GradoClinicoDropDown = uidropdown(app.UIFigure);
            app.GradoClinicoDropDown.Items = {'0', '1', '2', '3', '4'};
            app.GradoClinicoDropDown.Value = '0';
            app.Rigidezk0NmradLabel = uilabel(app.UIFigure);
            app.Rigidezk0NmradLabel.Text = 'Rigidez (N·m/rad)';
            app.RigidezNmradEditField = uieditfield(app.UIFigure, 'numeric');
            app.RigidezNmradEditField.Value = 0.65;
            app.AmortiguamientoNmsradEditFieldLabel = uilabel(app.UIFigure);
            app.AmortiguamientoNmsradEditFieldLabel.Text = 'Amortiguamiento (N·m·s/rad)';
            app.AmortiguamientoNmsradEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmortiguamientoNmsradEditField.Value = 0.03;
            app.LongitudmEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudmEditFieldLabel.Text = 'Longitud (m)';
            app.LongitudmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudmEditField.Value = 0.15;
            app.ANALIZARTEMBLORButton = uibutton(app.UIFigure, 'push');
            app.ANALIZARTEMBLORButton.ButtonPushedFcn = createCallbackFcn(app, @ANALIZARTEMBLORButtonPushed, true);
            app.ANALIZARTEMBLORButton.Text = 'ANALIZAR TEMBLOR';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.DuracinsEditFieldLabel = uilabel(app.UIFigure);
            app.DuracinsEditFieldLabel.Text = 'Duración (s)';
            app.DuracionEditField = uieditfield(app.UIFigure, 'numeric');
            app.DuracionEditField.Value = 5;
            app.FrecMuestreoHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrecMuestreoHzEditFieldLabel.Text = 'Frec. Muestreo (Hz)';
            app.FrecMuestreoEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecMuestreoEditField.Value = 100;
            app.IncluirArmonicosCheckBox = uicheckbox(app.UIFigure);
            app.IncluirArmonicosCheckBox.Text = 'Incluir Armónicos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz_tfg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end